classdef waveanalyze_v52 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        stopButton            matlab.ui.control.Button
        playButton            matlab.ui.control.Button
        noiseapplyButton      matlab.ui.control.Button
        By2025020226Label     matlab.ui.control.Label
        PSDButton             matlab.ui.control.StateButton
        WindowFucDropDown     matlab.ui.control.DropDown
        Label_16              matlab.ui.control.Label
        SamplingPointSpinner  matlab.ui.control.Spinner
        Label_15              matlab.ui.control.Label
        FilterOrderSpinner    matlab.ui.control.Spinner
        Label_14              matlab.ui.control.Label
        ResetFilterButton     matlab.ui.control.Button
        ApplyFliterButton     matlab.ui.control.Button
        Freq2Spinner          matlab.ui.control.Spinner
        Label_13              matlab.ui.control.Label
        Freq1Spinner          matlab.ui.control.Spinner
        Label_12              matlab.ui.control.Label
        FilterTypeDropDown    matlab.ui.control.DropDown
        Label_11              matlab.ui.control.Label
        fsSpinner             matlab.ui.control.Spinner
        Label_10              matlab.ui.control.Label
        FFTButton             matlab.ui.control.Button
        SNRSpinner            matlab.ui.control.Spinner
        SNRLabel              matlab.ui.control.Label
        snrDropDown           matlab.ui.control.DropDown
        Label_9               matlab.ui.control.Label
        phi                   matlab.ui.control.NumericEditField
        Label_6               matlab.ui.control.Label
        omega                 matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Label_8               matlab.ui.control.Label
        amplitude             matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        openfile              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        FFTAxes               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
       audioData=[]
       filteredData = [];       % 存储滤波后的数据 
       isFiltered = false;      % 标记是否已应用滤波
       originalData = [];       % 存储原始数据备份
       Player;

       %更新说明
       UpdateNotes = {...
           '9.28 新增带窗函数的FFT变换和PSD计算';...
           '10.13 新增音频播放功能';...
           '10.15 修复了导入音频播放报错的问题'
       }
    end
    
    methods (Access = private)
        
        function startupFcn(app)
    % 在这里添加初始化代码
    app.audioData=[];
    app.fs.Value=44100;
    
    % 设置默认选择正弦波
    app.SignalTypeButtonGroup.SelectedObject = app.SineWaveButton;
    
    % 调用信号类型选择回调以更新UI状态 
    SignalTypeButtonGroupSelectionChanged(app);
    
    % 初始禁用FFT按钮
    %app.FFTButton.Enable = 'off';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %生成x
            x=0:0.01:10;
            fuc=app.DropDown.Value;                      
            a=app.amplitude.Value;  %幅值
            ome=app.omega.Value;  %频率
            ang=app.phi.Value; %相位
            %switch fuc
            %case '正弦信号'
                y= a*sin(ome*x+ang);
                %snrdd=app.snrDropDown.Value   %噪声类型
                plot(app.UIAxes,x,y);
                app.audioData=y;
            
            
         %app.FFTButton.Enable = 'on';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
                     
        end

        % Value changed function: SNRSpinner
        function SNRSpinnerValueChanged(app, event)
   
        end

        % Button pushed function: openfile
        function openfilePushed(app, event)
            %选择音频文件
            [filename,pathname]=uigetfile({'*.wav;*.mp3','Audio Files(*.wav,*.mp3)'},'Select an audio file');
            if isequal(filename,0)
                return; %用户取消选择
            end

            %读取音频文件
            fullpath=fullfile(pathname,filename);
            try
                [audioData,fs]=audioread(fullpath);
                x=(0:length(audioData)-1)/fs;
                y=audioData;
                plot(app.UIAxes,x,y)
                app.audioData=y; 
            end
            %app.FFTButton.Enable = 'on';
        end

        % Button pushed function: FFTButton
        function FFTButtonPushed(app, event)
              
    y = app.audioData; 
    fs = app.fsSpinner.Value; 
    N = length(y);
    wf = app.WindowFucDropDown.Value
    
    % 计算FFT
    switch wf
        
        case '无'
            yw=y;
            window=1;
            windowName='无窗函数';
        case '汉宁窗'
            window=hann(N);
            windowName='汉宁窗';
        case '矩形窗'
            window=ones(N,1);
            windowName='矩形窗';
        case '汉明窗'
            window=hamming(N);
            windowName='汉明窗';
        case '平顶窗'
            window=flattopwin(N);
            windowName='平顶窗';
     
    end
    
    %if ~strcmp(app.WindowFucDropDown, '无')
        yw=y.*window;
    %end
    
    %计算FFT
    ywfft=fft(yw);
    P2=abs(ywfft/N);
    P1=P2(1:N/2+1);
    P1(2:end-1)=2*P1(2:end-1);
    f=fs*(0:(N/2))/N;

    %绘制时域信号
    t=(0:N-1)/fs;
    plot(app.FFTAxes, t, ywfft);
    title(app.FFTAxes,['时域信号：', windowName]);
    xlabel(app.FFTAxes, '时间/s');
    ylabel(app.FFTAxes, '幅度');
    grid(app.FFTAxes,'on');

    % 绘制频域图 
    %plot(app.FFTAxes, f, P1);
    %xlabel(app.FFTAxes, '频率 (Hz)');
    %ylabel(app.FFTAxes, '幅度');
    %title(app.FFTAxes, windowName);

        end

        % Button pushed function: ApplyFliterButton
        function ApplyFliterButtonPushed(app, event)
        % 检查是否有音频数据
    if isempty(app.audioData) 
        uialert(app.UIFigure, '请先加载或生成音频信号', '错误');
        return;
    end

    fs=app.fsSpinner.Value;
    order=app.FilterOrderSpinner.Value;
    freq1=app.Freq1Spinner.Value;
    freq2=app.Freq2Spinner.Value;
    filterType=app.FilterTypeDropDown.Value;
    sampling=app.SamplingPointSpinner.Value;

    if fs==0
        uialert(app.UIFigure,'采样率不能为0！', '错误');
    end
        switch filterType
            case '低通'
                [b,a]=butter(order, freq2/(fs/2));
                
            case '高通'
                [b,a]=butter(order, freq1/(fs/2));

            case '带通'
                [b,a]=butter(order, [freq1, freq2]/(fs/2));

            case '带阻'
                [b,a]=butter(order, [freq1, freq2]/(fs/2));
        end
    
        figure(1);
        freqz(b,a,sampling,fs);
                subplot(2,1,1);
                ylim=([-100,20])

        app.filteredData=filtfilt(b,a,app.originalData);
        %app.audioData=app.filteredData;
        app.isFiltered=true;

        end

        % Value changed function: PSDButton
        function PSDButtonValueChanged(app, event)
            x = app.audioData;
            fs = app.fsSpinner.Value;
            x0=(0:length(x)-1)/fs;
            wf = app.WindowFucDropDown.Value;
            constOne = @(~) 1;

        switch wf    
            case '无'
            window=constOne(length(x));
            case '汉宁窗'
            window=hann(length(x));
            case '矩形窗'
            window=ones(length(x));
            case '汉明窗'
            window=hamming(length(x));
            case '平顶窗'
            window=flattopwin(length(x)); 
        end

            %计算PSD
            [psdd, f] = periodogram(x0, window, [], fs);

            %绘制结果
            plot(app.FFTAxes, f, 10*log10(psdd));
            grid(app.FFTAxes, 'on');
            title(app.FFTAxes, '功率谱密度估计');
            xlabel(app.FFTAxes, '频率(Hz)');
            ylabel(app.FFTAxes, ('功率/频率(dB/Hz)'))
        end

        % Button pushed function: noiseapplyButton
        function noiseapplyButtonPushed(app, event)
            x=0:0.01:10;
            y1 = app.audioData;
            fuc = app.DropDown.Value;
            snrdd = app.snrDropDown.Value;
            snr=app.SNRSpinner.Value;   %信噪比
           
                       %if fuc==string('正弦信号')
                        if snrdd==('高斯白噪声')
                        noisepower=var(y1)/(10^(snr/10)); %计算噪声功率
                        noise=sqrt(noisepower).*randn(size(y1)) %生成高斯白噪声
                        ynoise=y1+noise
                        %plot(app.UIAxes,x,ynoise);
                        app.audioData = ynoise; %添加噪声，需要实时储存
                        end
                        %if snrdd==('无')
                        %plot(app.UIAxes,x,ynoise);
                        %app.audioData=y1 %波形不变，不需要实时储存
                        %end
                   %end
            %}
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            % 检查音频数据是否存在 
    if isempty(app.audioData)
        uialert(app.UIFigure, '请先导入音频文件', '数据缺失');
        return;
    end
    fs = app.fsSpinner.Value;
    
    % 创建或重启播放器 
    if isempty(app.Player) || ~isplaying(app.Player)
        app.Player = audioplayer(app.audioData, fs);
    end  
        % 设置播放进度回调
       % set(app.Player, 'TimerFcn', @(~,~) app.updateProgress,  ...
         %              'TimerPeriod', 0.05);
        
        % 开始播放
        play(app.Player);
        app.playButton.Text = '▶ 播放中...';
        app.playButton.Enable = 'off';
        app.stopButton.Enable = 'on';
        

        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            %if isempty(app.Player) && isplaying(app.Player)
                stop(app.Player);
                app.playButton.Text = '播放音频';
                app.playButton.Enable = 'on';
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '信号图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FFTAxes = uiaxes(app.UIFigure);
            title(app.FFTAxes, '变换分析')
            xlabel(app.FFTAxes, 'X')
            ylabel(app.FFTAxes, 'Y')
            zlabel(app.FFTAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '信号分析器';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成波形';
            app.openfile = uibutton(app.UIFigure, 'push');
            app.openfile.ButtonPushedFcn = createCallbackFcn(app, @openfilePushed, true);
            app.openfile.Text = '导入音频文件';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号参数';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '幅值';
            app.amplitude = uieditfield(app.UIFigure, 'numeric');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '信号类型';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'正弦信号'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '正弦信号';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '频率';
            app.omega = uieditfield(app.UIFigure, 'numeric');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '相位';
            app.phi = uieditfield(app.UIFigure, 'numeric');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '噪声类型';
            app.snrDropDown = uidropdown(app.UIFigure);
            app.snrDropDown.Items = {'无', '高斯白噪声'};
            app.snrDropDown.Value = '无';
            app.SNRLabel = uilabel(app.UIFigure);
            app.SNRLabel.Text = '信噪比（SNR）';
            app.SNRSpinner = uispinner(app.UIFigure);
            app.SNRSpinner.Limits = [-50 50];
            app.SNRSpinner.ValueChangedFcn = createCallbackFcn(app, @SNRSpinnerValueChanged, true);
            app.FFTButton = uibutton(app.UIFigure, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFTButtonPushed, true);
            app.FFTButton.Text = 'FFT变换';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '采样率';
            app.fsSpinner = uispinner(app.UIFigure);
            app.fsSpinner.Step = 100;
            app.fsSpinner.Limits = [1000 100000];
            app.fsSpinner.Value = 1000;
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '滤波器类型';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'无', '低通', '高通', '带通', '带阻'};
            app.FilterTypeDropDown.Value = '无';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '低临界频率';
            app.Freq1Spinner = uispinner(app.UIFigure);
            app.Freq1Spinner.Step = 100;
            app.Freq1Spinner.Limits = [0 100000];
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '高临界频率';
            app.Freq2Spinner = uispinner(app.UIFigure);
            app.Freq2Spinner.Step = 100;
            app.Freq2Spinner.Limits = [0 100000];
            app.ApplyFliterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFliterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFliterButtonPushed, true);
            app.ApplyFliterButton.Text = '应用滤波';
            app.ResetFilterButton = uibutton(app.UIFigure, 'push');
            app.ResetFilterButton.Text = '重置音频';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '滤波器阶数';
            app.FilterOrderSpinner = uispinner(app.UIFigure);
            app.FilterOrderSpinner.Limits = [1 10];
            app.FilterOrderSpinner.Value = 1;
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '采样点';
            app.SamplingPointSpinner = uispinner(app.UIFigure);
            app.SamplingPointSpinner.Step = 128;
            app.SamplingPointSpinner.Limits = [128 2048];
            app.SamplingPointSpinner.Value = 128;
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '窗函数';
            app.WindowFucDropDown = uidropdown(app.UIFigure);
            app.WindowFucDropDown.Items = {'无', '汉宁窗', '汉明窗', '矩形窗', '平顶窗'};
            app.WindowFucDropDown.Value = '无';
            app.PSDButton = uibutton(app.UIFigure, 'state');
            app.PSDButton.ValueChangedFcn = createCallbackFcn(app, @PSDButtonValueChanged, true);
            app.PSDButton.Text = 'PSD计算';
            app.By2025020226Label = uilabel(app.UIFigure);
            app.By2025020226Label.Text = 'By 李明远 2025020226';
            app.noiseapplyButton = uibutton(app.UIFigure, 'push');
            app.noiseapplyButton.ButtonPushedFcn = createCallbackFcn(app, @noiseapplyButtonPushed, true);
            app.noiseapplyButton.Text = '添加噪声';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = '播放音频';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = '停止播放';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveanalyze_v52

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end